classdef CarDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        carDetection       matlab.ui.Figure
        Panel              matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        GridLayout3        matlab.ui.container.GridLayout
        CarDetectionLabel  matlab.ui.control.Label
        Image5             matlab.ui.control.Image
        GridLayout4        matlab.ui.container.GridLayout
        GridLayout6        matlab.ui.container.GridLayout
        imagePanel         matlab.ui.control.UIAxes
        textPanel          matlab.ui.control.TextArea
        videoPanel         matlab.ui.control.UIAxes
        GridLayout5        matlab.ui.container.GridLayout
        openVideo          matlab.ui.control.Image
        stopImage          matlab.ui.control.Image
        Image7             matlab.ui.control.Image
    end

    
    properties (Access = private)
        executing
        stop
    end

    
    methods (Access = public)
        
       function r = getStop(app)
            r = app.stop;
       end
       
        function resetPanels(app)
            % videoPanel
            app.videoPanel = uiaxes(app.GridLayout4);
            title(app.videoPanel, '')
            xlabel(app.videoPanel, '')
            ylabel(app.videoPanel, '')
            app.videoPanel.XTick = [];
            app.videoPanel.YTick = [];
            app.videoPanel.BackgroundColor = [0 0 0];
            app.videoPanel.Layout.Row = 1;
            app.videoPanel.Layout.Column = 1;

            % imagePanel
            app.imagePanel = uiaxes(app.GridLayout4);
            title(app.imagePanel, '')
            xlabel(app.imagePanel, '')
            ylabel(app.imagePanel, '')
            app.imagePanel.XTick = [];
            app.imagePanel.XTickLabel = '';
            app.imagePanel.YTick = [];
            app.imagePanel.YTickLabel = '';
            app.imagePanel.BackgroundColor = [0 0 0];
            app.imagePanel.Layout.Row = 1;
            app.imagePanel.Layout.Column = 2;
        end
        
        function update(app, context)
            event = getEvent(context);
            switch event
            case Events.GUI_CAR_DETECTION
                app.carDetection.Visible = true;
                drawnow;
                app.carDetection.WindowState = 'maximized';
            case Events.EXECUTE_CAR_DETECTION_OK
                app.resetPanels();
                uialert(app.carDetection,'The process has ended','Success', 'Icon','success');
            case Events.EXECUTE_CAR_DETECTION_KO
                app.resetPanels();
                data = getData(context);
                uialert(app.carDetection, data, 'Error', 'Icon','error');
            case Events.EXECUTE_CAR_DETECTION_STOP
                app.resetPanels();
                data = getData(context);
                uialert(app.carDetection, data,'Success', 'Icon','success');
            case Events.UPDATE_CAR_DETECTION_VIDEO_DATA
                data = context.getData();
                if(~isnan(data.getVideoImage()))
                   imshow(data.getVideoImage(), 'parent', app.videoPanel);
                end
                if(~isnan(data.getDetectImage()))
                   imshow(data.getDetectImage(), 'parent', app.imagePanel);
                end
                if(~isnan(data.getDetectionData()))
                   text = data.getDetectionData();
                   app.textPanel.Value{end+1} = 'Asphalt - FrontBus - BackBus - FrontCar - BackCar - FrontMotorbike - BackMotorbike - FrontTruck-Van - FrontTruck-Van - Wall';
                   app.textPanel.Value{end+1} = text(1);
                   app.textPanel.Value{end+1} = strcat('Winner category: ', text(2));
                   app.textPanel.Value{end+1} = '';
                end
                if (~ismethod(data.getTCarDetectionData(),'isnan')) 
                   tCarDetectionData = data.getTCarDetectionData();
                   XSupIzda = tCarDetectionData.getXSupIzda();
                   XSupDcha = tCarDetectionData.getXSupDcha();
                   XInfIzda = tCarDetectionData.getXInfIzda();
                   XInfDcha = tCarDetectionData.getXInfDcha();
                   YSupIzda = tCarDetectionData.getYSupIzda();
                   YSupDcha = tCarDetectionData.getYSupDcha();
                   YInfIzda = tCarDetectionData.getYInfIzda();
                   YInfDcha = tCarDetectionData.getYInfDcha();
                   category = tCarDetectionData.getCategory();
                   color = tCarDetectionData.getColor();
                   
                   % category
                   
                   % x = str2num(convertStringsToChars(XSupDcha)) 
                   % y = str2num(convertStringsToChars(YSupDcha))
                   % class(XSupDcha)
            
                   % x = str2num([XSupDcha]) 
                   % y = str2num([YSupDcha])
                   
                   x = int64(XSupDcha);
                   y = int64(YSupDcha);
     
                   disp(["X",x,class(x)]);
                   disp(["Y",y,class(y)]);
     
                   % text(x,y,'Hola', 'FontSize',14, 'Color',color, 'FontWeight', 'bold');
                   
                   hold(app.videoPanel,'on');
                   % text(10, 10, 'hola', 'Fontsize',20); % 40
                   % text('hola','FontSize', 20);
                   line([XSupIzda,XSupDcha],[YSupIzda,YSupDcha],'LineWidth',3,'Color',color);
                   line([XSupIzda,XInfIzda],[YSupIzda,YInfIzda],'LineWidth',3,'Color',color);
                   line([XSupDcha,XInfDcha],[YSupDcha,YInfDcha],'LineWidth',3,'Color',color);
                   line([XInfIzda,XInfDcha],[YInfIzda,YInfDcha],'LineWidth',3,'Color',color);
                   text(300,300,'HOLA MUNDO','Parent',app.videoPanel);
                   hold(app.videoPanel,'off');


                end
                drawnow;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.carDetection.Visible = false;
            executing = false;
            stop = false;
            drawnow;
            app.carDetection.WindowState = 'maximized';
        end

        % Image clicked function: openVideo
        function openVideoImageClicked(app, event)
            if app.executing
                app.stop = true;
                pause(1);
                app.executing = false;
            end
            [videoName, directory]= uigetfile({'*.*','All files'});
            if(~isequal(videoName,0))
                [f,c] = size(regexp(videoName,'\.(mp4|avi|mpg|mpeg|MPEG-4|.m4v|mj2)','match'));
                if c
                    app.stop = false;
                    app.executing = true;
                    tCarDetection = TCarDetection(directory, videoName, @app.getStop);
                    %spmd
                    Controler.getInstance().execute(Context(Events.EXECUTE_CAR_DETECTION, tCarDetection));
                    %end
                else
                    uialert(app.carDetection,"This file is not valid", 'Error', 'Icon','error');
                end
            end
            
        end

        % Image clicked function: Image7
        function Image7Clicked(app, event)
            if app.executing
                app.stop = true;
                pause(1);
                app.executing = false;
            end
            app.carDetection.Visible = false;
            execute(Controler.getInstance(),Context(Events.GUI_MAIN, NaN));
        end

        % Image clicked function: stopImage
        function stopImageClicked(app, event)
            if app.executing
                app.stop = true;
                app.executing = false;
            end
        end

        % Close request function: carDetection
        function carDetectionCloseRequest(app, event)
            close all force;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.carDetection = uifigure('Visible', 'off');
            app.carDetection.Name = 'Smart Cities';
            app.carDetection.CloseRequestFcn = createCallbackFcn(app, @carDetectionCloseRequest, true);
            app.Panel = uipanel(app.carDetection);
            app.GridLayout = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.CarDetectionLabel = uilabel(app.GridLayout3);
            app.CarDetectionLabel.Text = 'Car Detection';
            app.Image5 = uiimage(app.GridLayout3);
            app.Image5.ImageSource = 'car.png';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.imagePanel = uiaxes(app.GridLayout6);
            title(app.imagePanel, '')
            xlabel(app.imagePanel, '')
            ylabel(app.imagePanel, '')
            app.imagePanel.XTick = [];
            app.imagePanel.XTickLabel = '';
            app.imagePanel.YTick = [];
            app.imagePanel.YTickLabel = '';
            app.textPanel = uitextarea(app.GridLayout6);
            app.textPanel.Editable = 'off';
            app.videoPanel = uiaxes(app.GridLayout4);
            title(app.videoPanel, '')
            xlabel(app.videoPanel, '')
            ylabel(app.videoPanel, '')
            app.videoPanel.XTick = [];
            app.videoPanel.YTick = [];
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.openVideo = uiimage(app.GridLayout5);
            app.openVideo.ImageClickedFcn = createCallbackFcn(app, @openVideoImageClicked, true);
            app.openVideo.ImageSource = 'play-button.png';
            app.stopImage = uiimage(app.GridLayout5);
            app.stopImage.ImageClickedFcn = createCallbackFcn(app, @stopImageClicked, true);
            app.stopImage.ImageSource = 'stop-houston-astros-logo-vector-png-transparent-houston-astros-16.png';
            app.Image7 = uiimage(app.GridLayout5);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @Image7Clicked, true);
            app.Image7.ImageSource = 'reply.png';
            % Show the figure after all components are created
            app.carDetection.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CarDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.carDetection)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.carDetection)
        end
    end
end