classdef Forecast < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        forecast              matlab.ui.Figure
        Panel                 matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        GridLayout3           matlab.ui.container.GridLayout
        ForecastLabel         matlab.ui.control.Label
        Image5                matlab.ui.control.Image
        GridLayout4           matlab.ui.container.GridLayout
        GridLayout12          matlab.ui.container.GridLayout
        UITable               matlab.ui.control.Table
        GridLayout13          matlab.ui.container.GridLayout
        DaystopredictLabel    matlab.ui.control.Label
        DaystopredictSpinner  matlab.ui.control.Spinner
        DayoftheweekLabel     matlab.ui.control.Label
        DayoftheweekDropDown  matlab.ui.control.DropDown
        ThepredictionbeginsonApril282020Label  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        GridLayout11          matlab.ui.container.GridLayout
        backImage             matlab.ui.control.Image
        searchImage           matlab.ui.control.Image
    end

    
    properties (Access = private)
        ThingSpeak
    end
    
    methods (Access = private)
        
        function search(app, day, prediction)
            %Hacer consulta
            channelIDWrite = 1004233;
            writeAPIKey = 'Y7EITP8OVYORQJOU';
            write(app.ThingSpeak, channelIDWrite, writeAPIKey, [1,2], [prediction, day]);
            uialert(app.forecast, "Making the prediction", 'Success', 'Icon', 'success');
            pause(22);
            [data,timestamps,channelInfo] = read(app.ThingSpeak, channelIDRead, readAPIKey, 1);
            app.fillTable(data, timestamps, channelInfo);
        end
        
        function fillTable(app, data, timestamps, channelInfo)
            %Funcion que rellena la tabla
            r = horzcat(string(timestamps), string(data));
            total = ["TOTAL", string(sum(data))];
            r = vertcat(r,total);
            set(app.UITable,'Data',cellstr(r));
            app.createGraphic(timestamps, data);
        end
        
        function createGraphic(app, timestamps, data)
            plot(timestamps, data,'-o','Parent', app.UIAxes);
            legend(app.UIAxes, app.UITable.ColumnName(2:1:end));
            minimo = min(min(data));
            if minimo > 0
                minimo = minimo-1;       
            end
            ylim(app.UIAxes,[minimo, max(max(data))+1]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.forecast.WindowState = 'maximized';
            app.ThingSpeak = ThingSpeakFunctions;
        end

        % Image clicked function: backImage
        function backImageClicked(app, event)
            close(app.forecast);
            SmartCities;
        end

        % Image clicked function: searchImage
        function searchImageClicked(app, event)
            day = app.DayoftheweekDropDown.Value;
            prediction = app.DaystopredictSpinner.Value;
            app.search(day, prediction);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.forecast = uifigure('Visible', 'off');
            app.forecast.Name = 'Smart Cities';
            app.Panel = uipanel(app.forecast);
            app.GridLayout = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ForecastLabel = uilabel(app.GridLayout3);
            app.ForecastLabel.Text = ' Forecast';
            app.Image5 = uiimage(app.GridLayout3);
            app.Image5.ImageSource = 'forecast-icon-17.jpg';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout12 = uigridlayout(app.GridLayout4);
            app.UITable = uitable(app.GridLayout12);
            app.UITable.ColumnName = {'Date'; 'Vehicles'};
            app.UITable.RowName = {''; ''};
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.DaystopredictLabel = uilabel(app.GridLayout13);
            app.DaystopredictLabel.Text = 'Days to predict:';
            app.DaystopredictSpinner = uispinner(app.GridLayout13);
            app.DaystopredictSpinner.Limits = [1 Inf];
            app.DaystopredictSpinner.RoundFractionalValues = 'on';
            app.DaystopredictSpinner.Value = 1;
            app.DayoftheweekLabel = uilabel(app.GridLayout13);
            app.DayoftheweekLabel.Text = 'Day of the week:';
            app.DayoftheweekDropDown = uidropdown(app.GridLayout13);
            app.DayoftheweekDropDown.Items = {'Monday', 'Tuesday', 'Wednesday', 'Thursday', 'Friday', 'Saturday', 'Sunday'};
            app.DayoftheweekDropDown.Value = 'Monday';
            app.ThepredictionbeginsonApril282020Label = uilabel(app.GridLayout4);
            app.ThepredictionbeginsonApril282020Label.Text = 'The prediction begins on April 28, 2020';
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.GridLayout11 = uigridlayout(app.GridLayout);
            app.backImage = uiimage(app.GridLayout11);
            app.backImage.ImageClickedFcn = createCallbackFcn(app, @backImageClicked, true);
            app.backImage.ImageSource = 'reply.png';
            app.searchImage = uiimage(app.GridLayout11);
            app.searchImage.ImageClickedFcn = createCallbackFcn(app, @searchImageClicked, true);
            app.searchImage.ImageSource = 'Search_ballonicon2.svg.png';
            % Show the figure after all components are created
            app.forecast.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Forecast

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.forecast)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.forecast)
        end
    end
end