classdef Learning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        learning                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        GridLayout4              matlab.ui.container.GridLayout
        ImageResizerImage        matlab.ui.control.Image
        ImageResizerLabel        matlab.ui.control.Label
        GridLayout5              matlab.ui.container.GridLayout
        UpdateTrainingDataLabel  matlab.ui.control.Label
        UpdateTrainingDataImage  matlab.ui.control.Image
        GridLayout6              matlab.ui.container.GridLayout
        RetrainingLabel          matlab.ui.control.Label
        Image10                  matlab.ui.control.Image
        GridLayout7              matlab.ui.container.GridLayout
        Image8                   matlab.ui.control.Image
        GridLayout3              matlab.ui.container.GridLayout
        LearningLabel_2          matlab.ui.control.Label
        Image5                   matlab.ui.control.Image
    end

    
    methods (Access = public)
        function update(app, context)
            event = getEvent(context);
            switch event
            case Events.GUI_LEARNING
                app.learning.Visible = true;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.learning.Visible = false;
            drawnow;
            app.learning.WindowState = 'maximized';
        end

        % Callback function
        function ImageClicked(app, event)
            app.learning.Visible = false;
        end

        % Image clicked function: Image8
        function Image8Clicked(app, event)
            app.learning.Visible = false;
            execute(Controler.getInstance(),Context(Events.GUI_MAIN, NaN));
        end

        % Image clicked function: Image10
        function RetrainingImageClicked(app, event)
            app.learning.Visible = false;
            execute(Controler.getInstance(),Context(Events.GUI_RETRAINING, NaN));
        end

        % Image clicked function: UpdateTrainingDataImage
        function UpdateTrainingDataImageClicked(app, event)
            winopen(fullfile(pwd,"Vehiculos"));
        end

        % Image clicked function: ImageResizerImage
        function ImageResizerImageClicked(app, event)
            close all, clear all;
            resize_image_folder = fullfile(pwd);
            resize_image_destiny_folder = fullfile(pwd,"Recortes");  
            [filename,user_canceled] = imgetfile('InitialPath',resize_image_folder,'MultiSelect',true);
            if ~user_canceled
                [nRow, nCol] = size(filename);
                for row=1:nRow
                    for col=1:nCol
                        I = imread(filename{row, col});
                        I = imresize(I, [227 227]);
                        [filepath,name,ext] = fileparts(filename{row, col});
                        imwrite(I,fullfile(resize_image_destiny_folder, strcat(name, ext)));
                    end
                end
                winopen(resize_image_destiny_folder);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.learning = uifigure('Visible', 'off');
            app.learning.Name = 'Smart Cities';
            app.Panel = uipanel(app.learning);
            app.GridLayout = uigridlayout(app.Panel);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.ImageResizerImage = uiimage(app.GridLayout4);
            app.ImageResizerImage.ImageClickedFcn = createCallbackFcn(app, @ImageResizerImageClicked, true);
            app.ImageResizerImage.ImageSource = 'logo-fb-en.png';
            app.ImageResizerLabel = uilabel(app.GridLayout4);
            app.ImageResizerLabel.Text = 'Image Resizer';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.UpdateTrainingDataLabel = uilabel(app.GridLayout5);
            app.UpdateTrainingDataLabel.Text = {'Update Training'; 'Data'};
            app.UpdateTrainingDataImage = uiimage(app.GridLayout5);
            app.UpdateTrainingDataImage.ImageClickedFcn = createCallbackFcn(app, @UpdateTrainingDataImageClicked, true);
            app.UpdateTrainingDataImage.ImageSource = 'archivador_24908-40157.jpg';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.RetrainingLabel = uilabel(app.GridLayout6);
            app.RetrainingLabel.Text = 'Retraining';
            app.Image10 = uiimage(app.GridLayout6);
            app.Image10.ImageClickedFcn = createCallbackFcn(app, @RetrainingImageClicked, true);
            app.Image10.ImageSource = '171-1712673_tuercas-png-logo-de-tuercas-png.png';
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.Image8 = uiimage(app.GridLayout7);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @Image8Clicked, true);
            app.Image8.ImageSource = 'reply.png';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.LearningLabel_2 = uilabel(app.GridLayout3);
            app.LearningLabel_2.Text = 'Learning';
            app.Image5 = uiimage(app.GridLayout3);
            app.Image5.ImageSource = 'machineLearning.png';
            % Show the figure after all components are created
            app.learning.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Learning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.learning)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.learning)
        end
    end
end