classdef prototipo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Campo1EditFieldLabel  matlab.ui.control.Label
        Campo1                matlab.ui.control.NumericEditField
        PROTOTIPOLabel        matlab.ui.control.Label
        Campo2EditFieldLabel  matlab.ui.control.Label
        Campo2                matlab.ui.control.NumericEditField
        Campo3EditFieldLabel  matlab.ui.control.Label
        Campo3                matlab.ui.control.NumericEditField
        GUARDARButton         matlab.ui.control.Button
        Campo4Label           matlab.ui.control.Label
        Campo4                matlab.ui.control.NumericEditField
        Campo5Label           matlab.ui.control.Label
        Campo5                matlab.ui.control.NumericEditField
        Campo6Label           matlab.ui.control.Label
        Campo6                matlab.ui.control.NumericEditField
        Campo7Label           matlab.ui.control.Label
        Campo7                matlab.ui.control.NumericEditField
        Campo8Label           matlab.ui.control.Label
        Campo8                matlab.ui.control.NumericEditField
        MOSTRARButton         matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GUARDARButton
        function GUARDARButtonPushed(app, event)
            ChannelIDParking = 880528;
            readAPIKeyParking = 'QS1OBBWZ0C28U7Q2';
            writeAPIKeyParking = 'LQYPEIS3NDTQPXSC';
            array = [app.Campo1.Value, app.Campo2.Value, app.Campo3.Value, app.Campo4.Value, app.Campo5.Value, app.Campo6.Value, app.Campo7.Value, app.Campo8.Value]
            respuesta = thingSpeakWrite(ChannelIDParking,array,'Writekey',writeAPIKeyParking);
            app.Campo1.Value = 0;
            pause(3);
            app.Campo2.Value = 0;
            pause(3);
            app.Campo3.Value = 0;
            pause(3);
            app.Campo4.Value = 0;
            pause(3);
            app.Campo5.Value = 0;
            pause(3);
            app.Campo6.Value = 0;
            pause(3);
            app.Campo7.Value = 0;
            pause(3);
            app.Campo8.Value = 0;
            pause(3);
        end

        % Button pushed function: MOSTRARButton
        function MOSTRARButtonPushed(app, event)
            ChannelIDParking = 880528;
            readAPIKeyParking = 'QS1OBBWZ0C28U7Q2';
            writeAPIKeyParking = 'LQYPEIS3NDTQPXSC';
            array = 1:8;
            respuesta = thingSpeakRead(ChannelIDParking,'Fields',array,'Readkey',readAPIKeyParking);
            disp(respuesta);
            app.Campo1.Value = respuesta(1);
            pause(3);
            app.Campo2.Value = respuesta(2);
            pause(3);
            app.Campo3.Value = respuesta(3);
            pause(3);
            app.Campo4.Value = respuesta(4);
            pause(3);
            app.Campo5.Value = respuesta(5);
            pause(3);
            app.Campo6.Value = respuesta(6);
            pause(3);
            app.Campo7.Value = respuesta(7);
            pause(3);
            app.Campo8.Value = respuesta(8);
            pause(3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Campo1EditFieldLabel = uilabel(app.UIFigure);
            app.Campo1EditFieldLabel.Text = 'Campo 1';
            app.Campo1 = uieditfield(app.UIFigure, 'numeric');
            app.PROTOTIPOLabel = uilabel(app.UIFigure);
            app.PROTOTIPOLabel.Text = 'PROTOTIPO';
            app.Campo2EditFieldLabel = uilabel(app.UIFigure);
            app.Campo2EditFieldLabel.Text = 'Campo 2';
            app.Campo2 = uieditfield(app.UIFigure, 'numeric');
            app.Campo3EditFieldLabel = uilabel(app.UIFigure);
            app.Campo3EditFieldLabel.Text = 'Campo 3';
            app.Campo3 = uieditfield(app.UIFigure, 'numeric');
            app.GUARDARButton = uibutton(app.UIFigure, 'push');
            app.GUARDARButton.ButtonPushedFcn = createCallbackFcn(app, @GUARDARButtonPushed, true);
            app.GUARDARButton.Text = 'GUARDAR';
            app.Campo4Label = uilabel(app.UIFigure);
            app.Campo4Label.Text = 'Campo 4';
            app.Campo4 = uieditfield(app.UIFigure, 'numeric');
            app.Campo5Label = uilabel(app.UIFigure);
            app.Campo5Label.Text = 'Campo 5';
            app.Campo5 = uieditfield(app.UIFigure, 'numeric');
            app.Campo6Label = uilabel(app.UIFigure);
            app.Campo6Label.Text = 'Campo 6';
            app.Campo6 = uieditfield(app.UIFigure, 'numeric');
            app.Campo7Label = uilabel(app.UIFigure);
            app.Campo7Label.Text = 'Campo 7';
            app.Campo7 = uieditfield(app.UIFigure, 'numeric');
            app.Campo8Label = uilabel(app.UIFigure);
            app.Campo8Label.Text = 'Campo 8';
            app.Campo8 = uieditfield(app.UIFigure, 'numeric');
            app.MOSTRARButton = uibutton(app.UIFigure, 'push');
            app.MOSTRARButton.ButtonPushedFcn = createCallbackFcn(app, @MOSTRARButtonPushed, true);
            app.MOSTRARButton.Text = 'MOSTRAR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prototipo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end