classdef Queries < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        queries                 matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        GridLayout3             matlab.ui.container.GridLayout
        ThingSpeakQueriesLabel  matlab.ui.control.Label
        Image5                  matlab.ui.control.Image
        GridLayout4             matlab.ui.container.GridLayout
        GridLayout5             matlab.ui.container.GridLayout
        GridLayout6             matlab.ui.container.GridLayout
        EndDateLabel            matlab.ui.control.Label
        EndDatePicker           matlab.ui.control.DatePicker
        GridLayout9             matlab.ui.container.GridLayout
        InitDateLabel           matlab.ui.control.Label
        InitDatePicker          matlab.ui.control.DatePicker
        GridLayout10            matlab.ui.container.GridLayout
        FrontCarCheckBox        matlab.ui.control.CheckBox
        BackCarCheckBox         matlab.ui.control.CheckBox
        FrontTruckCheckBox      matlab.ui.control.CheckBox
        BackTruckCheckBox       matlab.ui.control.CheckBox
        FrontMotorbikeCheckBox  matlab.ui.control.CheckBox
        BackMotorbikeCheckBox   matlab.ui.control.CheckBox
        FrontBusCheckBox        matlab.ui.control.CheckBox
        BackBusCheckBox         matlab.ui.control.CheckBox
        UIAxes                  matlab.ui.control.UIAxes
        UITable                 matlab.ui.control.Table
        GridLayout11            matlab.ui.container.GridLayout
        backImage               matlab.ui.control.Image
        searchImage             matlab.ui.control.Image
    end

    
    properties (Access = private)
        vehicleTypeSelectors
    end
    
    methods (Access = private)
        
        function search(app, fields, initDate, endDate)
            %Hacer consulta
            
            %{
            disp('Campo fields, que tendra los coches');
            disp(fields)
            disp('Formato fechas');
            disp(initDate);
            'DateRange',[initDate,endDate],
            %}
            disp('Data');
            ChannelIDParking = 986255;
            readAPIKeyParking = 'WB89SEHHHJYDEFFL';
            
            
            % Convierto las fechas en arrays de strings
            firstDate = split(string(initDate), "-");
            secondDate = split(string(endDate), "-");
            
            % Realizo la consulta leyendo los datos de los campos deseados
            respuesta = thingSpeakRead(ChannelIDParking,'Fields',fields,'DateRange',...
            [datetime(str2num(firstDate(1)),str2num(firstDate(2)),str2num(firstDate(3)),23,59,01),...
            datetime(str2num(secondDate(1)),str2num(secondDate(2)),str2num(secondDate(3)),23,59,01)], ...
            'Readkey',readAPIKeyParking);
            
            disp('Data');
            disp(respuesta);
             
            %Llamar con el resultado a funcion que crea la tabla
        end
        
        function fillTable(app)
            %Funcion que rellena la tabla
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.queries.WindowState = 'maximized';
            app.vehicleTypeSelectors = [app.FrontCarCheckBox, app.BackCarCheckBox,...
                app.FrontTruckCheckBox, app.BackTruckCheckBox, ...
                app.FrontMotorbikeCheckBox, app.BackMotorbikeCheckBox,...
                app.FrontBusCheckBox, app.BackBusCheckBox];
        end

        % Image clicked function: backImage
        function backImageClicked(app, event)
            close(app.queries);
            ThingSpeak;
        end

        % Image clicked function: searchImage
        function searchImageClicked(app, event)
            
            fields = int16.empty();
            %fields = [];
            for i=1:8
                if  get(app.vehicleTypeSelectors(i),'value')
                    fields(end+1) = i;
                end
            end
            initDate = get(app.InitDatePicker,'value');
            endDate = get(app.EndDatePicker,'value');
            % Esta condicion no esta funcionando, REVISAR, comprobar que la
            % fecha inicio es menor que la fecha de fin
            if isnat(initDate)
                %Mensaje de error
                disp("Error initFecha");
            elseif isnat(endDate)
                disp("Error endFecha");
                %Mensaje de error    
            elseif isempty(fields)
                %Mensaje de error
                disp("Error Campos");
           
            else 
                % Para convertir las fechas en numeros para poder
                % compararlas
                formatIn = 'yyyy-mm-dd';
                dates = datenum([string(initDate),string(endDate)],formatIn);
                % La fecha fin(dates(2)), es menor que la fecha de inicio(dates(1))
                if dates(2) < dates(1)
                    %Mensaje de error
                    disp("Error initFecha no es menor que endFecha");
                   
                else %Todo ha salido bien
                    app.search(fields, initDate, endDate);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.queries = uifigure('Visible', 'off');
            app.queries.Name = 'Smart Cities';
            app.Panel = uipanel(app.queries);
            app.GridLayout = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ThingSpeakQueriesLabel = uilabel(app.GridLayout3);
            app.ThingSpeakQueriesLabel.Text = 'ThingSpeak Queries';
            app.Image5 = uiimage(app.GridLayout3);
            app.Image5.ImageSource = 'google-big-query-logo.png';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.EndDateLabel = uilabel(app.GridLayout6);
            app.EndDateLabel.Text = 'End Date:';
            app.EndDatePicker = uidatepicker(app.GridLayout6);
            app.EndDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.InitDateLabel = uilabel(app.GridLayout9);
            app.InitDateLabel.Text = 'Init Date:';
            app.InitDatePicker = uidatepicker(app.GridLayout9);
            app.InitDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.GridLayout10 = uigridlayout(app.GridLayout4);
            app.FrontCarCheckBox = uicheckbox(app.GridLayout10);
            app.FrontCarCheckBox.Text = 'Front Car';
            app.BackCarCheckBox = uicheckbox(app.GridLayout10);
            app.BackCarCheckBox.Text = 'Back Car';
            app.FrontTruckCheckBox = uicheckbox(app.GridLayout10);
            app.FrontTruckCheckBox.Text = 'Front Truck';
            app.BackTruckCheckBox = uicheckbox(app.GridLayout10);
            app.BackTruckCheckBox.Text = 'Back Truck';
            app.FrontMotorbikeCheckBox = uicheckbox(app.GridLayout10);
            app.FrontMotorbikeCheckBox.Text = 'Front Motorbike';
            app.BackMotorbikeCheckBox = uicheckbox(app.GridLayout10);
            app.BackMotorbikeCheckBox.Text = 'Back Motorbike';
            app.FrontBusCheckBox = uicheckbox(app.GridLayout10);
            app.FrontBusCheckBox.Text = 'Front Bus';
            app.BackBusCheckBox = uicheckbox(app.GridLayout10);
            app.BackBusCheckBox.Text = 'Back Bus';
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Graphic')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {'Date'; 'Front Car'; 'Back Car'; 'Front Truck'; 'Back Truck'; 'Front Motorbike'; 'Back Motorbike'; 'Front Bus'; 'Back Bus'};
            app.UITable.RowName = {};
            app.GridLayout11 = uigridlayout(app.GridLayout);
            app.backImage = uiimage(app.GridLayout11);
            app.backImage.ImageClickedFcn = createCallbackFcn(app, @backImageClicked, true);
            app.backImage.ImageSource = 'reply.png';
            app.searchImage = uiimage(app.GridLayout11);
            app.searchImage.ImageClickedFcn = createCallbackFcn(app, @searchImageClicked, true);
            app.searchImage.ImageSource = 'Search_ballonicon2.svg.png';
            % Show the figure after all components are created
            app.queries.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Queries

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.queries)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.queries)
        end
    end
end