classdef ThingSpeak < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        thingSpeak          matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        GridLayout          matlab.ui.container.GridLayout
        GridLayout2         matlab.ui.container.GridLayout
        GridLayout4         matlab.ui.container.GridLayout
        GridLayout5         matlab.ui.container.GridLayout
        QueriesLabel        matlab.ui.control.Label
        QueriesImage        matlab.ui.control.Image
        GridLayout6         matlab.ui.container.GridLayout
        ThingSpeakWebImage  matlab.ui.control.Image
        ThingSpeakWebLabel  matlab.ui.control.Label
        GridLayout7         matlab.ui.container.GridLayout
        Image8              matlab.ui.control.Image
        GridLayout3         matlab.ui.container.GridLayout
        ThingSpeakLabel     matlab.ui.control.Label
        Image5              matlab.ui.control.Image
    end

    
    properties (Access = private)
        app.learning.WindowState = 'maximized';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.thingSpeak.WindowState = 'maximized';
        end

        % Callback function
        function ImageClicked(app, event)
            close(app.thingSpeak);
        end

        % Image clicked function: Image8
        function Image8Clicked(app, event)
            close(app.thingSpeak);
            SmartCities;
        end

        % Image clicked function: ThingSpeakWebImage
        function ThingSpeakWebImageClicked(app, event)
            web https://thingspeak.com/channels/880528 -browser
        end

        % Image clicked function: QueriesImage
        function QueriesImageClicked(app, event)
            winopen(fullfile(pwd,"Vehiculos"));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.thingSpeak = uifigure('Visible', 'off');
            app.thingSpeak.Name = 'Smart Cities';
            app.Panel = uipanel(app.thingSpeak);
            app.GridLayout = uigridlayout(app.Panel);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.QueriesLabel = uilabel(app.GridLayout5);
            app.QueriesLabel.Text = 'Queries';
            app.QueriesImage = uiimage(app.GridLayout5);
            app.QueriesImage.ImageClickedFcn = createCallbackFcn(app, @QueriesImageClicked, true);
            app.QueriesImage.ImageSource = 'google-big-query-logo.png';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.ThingSpeakWebImage = uiimage(app.GridLayout6);
            app.ThingSpeakWebImage.ImageClickedFcn = createCallbackFcn(app, @ThingSpeakWebImageClicked, true);
            app.ThingSpeakWebImage.ImageSource = 'ThingSpeak-Web.png';
            app.ThingSpeakWebLabel = uilabel(app.GridLayout6);
            app.ThingSpeakWebLabel.Text = 'ThingSpeak Web';
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.Image8 = uiimage(app.GridLayout7);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @Image8Clicked, true);
            app.Image8.ImageSource = 'reply.png';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ThingSpeakLabel = uilabel(app.GridLayout3);
            app.ThingSpeakLabel.Text = 'ThingSpeak';
            app.Image5 = uiimage(app.GridLayout3);
            app.Image5.ImageSource = 'thingspeak_logo2.png';
            % Show the figure after all components are created
            app.thingSpeak.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThingSpeak

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.thingSpeak)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.thingSpeak)
        end
    end
end