classdef Forecast < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        forecast              matlab.ui.Figure
        Panel                 matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        GridLayout3           matlab.ui.container.GridLayout
        ForecastLabel         matlab.ui.control.Label
        Image5                matlab.ui.control.Image
        GridLayout4           matlab.ui.container.GridLayout
        GridLayout12          matlab.ui.container.GridLayout
        UITable               matlab.ui.control.Table
        GridLayout13          matlab.ui.container.GridLayout
        DaystopredictLabel    matlab.ui.control.Label
        DaystopredictSpinner  matlab.ui.control.Spinner
        DayoftheweekLabel     matlab.ui.control.Label
        DayoftheweekDropDown  matlab.ui.control.DropDown
        ThepredictionbeginsonMarch2020Label  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        GridLayout11          matlab.ui.container.GridLayout
        backImage             matlab.ui.control.Image
        searchImage           matlab.ui.control.Image
    end

    
    properties (Access = private)
        ThingSpeak
    end
    
    methods (Access = public)
        function update(app, context)
            import src.Controler.*
            import src.Context.*
            import src.Transfer.*
            event = getEvent(context);
            switch event
            case Events.GUI_FORECAST
                app.forecast.Visible = true;
                drawnow;
                app.forecast.WindowState = 'maximized';
            case Events.EXECUTE_FORECAST_READ_OK
                app.forecast.Visible = true;
                drawnow;
                app.forecast.WindowState = 'maximized';
                app.resetOutputPanels();
                app.createEstructure(context.getData());
            case Events.EXECUTE_FORECAST_READ_KO
                data = context.getData();
                app.forecast.Visible = true;
                drawnow;
                app.forecast.WindowState = 'maximized';
                uialert(app.forecast, data, 'Error', 'Icon','error');
            case Events.EXECUTE_FORECAST_WRITE_OK
                app.forecast.Visible = true;
                drawnow;
                app.forecast.WindowState = 'maximized';
                uialert(app.forecast, "Reading the prediction", 'Success', 'Icon', 'success');
                pause(10);
                tThingSpeak = app.createData(context.getData());
                Controler.getInstance().execute(Context(Events.EXECUTE_FORECAST_READ, tThingSpeak));
            case Events.EXECUTE_FORECAST_WRITE_KO
                data = context.getData();
                app.forecast.Visible = true;
                drawnow;
                app.forecast.WindowState = 'maximized';
                uialert(app.forecast, data, 'Error', 'Icon','error');
            end
        end
    end
    
    methods (Access = private)
        
        function resetOutputPanels(app)
            % Create UITable
            app.UITable = uitable(app.GridLayout12);
            app.UITable.ColumnName = {'Date'; 'Vehicles'};
            app.UITable.ColumnWidth = {'auto'};
            app.UITable.RowName = {''; ''};
            app.UITable.FontName = 'Pacifico';
            app.UITable.Layout.Row = 1;
            app.UITable.Layout.Column = 2;
            app.UITable.FontSize = 15;
            
            % Create UIAxes
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Prediction')
            xlabel(app.UIAxes, 'Dates')
            ylabel(app.UIAxes, 'Vehicles')
            app.UIAxes.FontName = 'Georgia';
            app.UIAxes.FontSize = 16;
            app.UIAxes.FontWeight = 'bold';
            app.UIAxes.Layout.Row = 3;
            app.UIAxes.Layout.Column = 1;
        end
        
        function resetInputPanels(app)
            % Create DaystopredictSpinner
            app.DaystopredictSpinner = uispinner(app.GridLayout13);
            app.DaystopredictSpinner.Limits = [1 Inf];
            app.DaystopredictSpinner.RoundFractionalValues = 'on';
            app.DaystopredictSpinner.FontName = 'Pacifico';
            app.DaystopredictSpinner.FontSize = 35;
            app.DaystopredictSpinner.Layout.Row = 1;
            app.DaystopredictSpinner.Layout.Column = 2;
            app.DaystopredictSpinner.Value = 1;
            
            % Create DayoftheweekDropDown
            app.DayoftheweekDropDown = uidropdown(app.GridLayout13);
            app.DayoftheweekDropDown.Items = {'Monday', 'Tuesday', 'Wednesday', 'Thursday', 'Friday', 'Saturday', 'Sunday'};
            app.DayoftheweekDropDown.FontName = 'Pacifico';
            app.DayoftheweekDropDown.FontSize = 35;
            app.DayoftheweekDropDown.FontWeight = 'bold';
            app.DayoftheweekDropDown.Layout.Row = 2;
            app.DayoftheweekDropDown.Layout.Column = 2;
            app.DayoftheweekDropDown.Value = 'Monday';
        end
        
        function tThingSpeak = createData(app, data)
            import src.Transfer.*
            prediction = data{1};
            day = data{2};
            pause(2);
            switch day
                case 'Monday'
                    field = 1;
                    t1 = datetime(2020,02,24);
                case 'Tuesday'
                   field = 2;
                   t1 = datetime(2020,02,25);
                case 'Wednesday'
                   field = 3;
                   t1 = datetime(2020,02,26);
                case 'Thursday'
                   field = 4;
                   t1 = datetime(2020,02,27);
                case 'Friday'
                   field = 5;
                   t1 = datetime(2020,02,28);
                case 'Saturday'
                   field = 6;
                   t1 = datetime(2020,02,29);
                case 'Sunday'
                   field = 7;
                   t1 = datetime(2020,02,23);
            end
            t1 = t1 + days(7);
            t2 = t1 + days(prediction*7);
            tThingSpeak = TThingSpeak(1005368, 'J2H43SHSB7JBIEWE', field, NaN, t1, t2, NaN); 
        end
        
        function createEstructure(app, tThingSpeakResult)
            import src.Transfer.*
            data = tThingSpeakResult.getData();
            timestamps = tThingSpeakResult.getTimestamps();
            T = table(data, timestamps);
            T = rmmissing(T);
            data = T.data;
            timestamps = T.timestamps;
            app.fillTable(data, timestamps);
        end
        
        function fillTable(app, data, timestamps)
            %Funcion que rellena la tabla
            r = horzcat(string(timestamps), string(round(data)));
            total = ["TOTAL", string(round(sum(data)))];
            r = vertcat(r,total);
            set(app.UITable,'Data',cellstr(r));
            app.createGraphic(timestamps, data);
        end
        
        function createGraphic(app, timestamps, data)
            plot(timestamps, round(data),'-o','Parent', app.UIAxes);
            legend(app.UIAxes, app.UITable.ColumnName(2:1:end));
            minimo = min(min(round(data)));
            if minimo > 9
                minimo = minimo-10;       
            end
            ylim(app.UIAxes,[minimo, max(max(round(data)))+10]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.forecast.Visible = false;
        end

        % Image clicked function: backImage
        function backImageClicked(app, event)
            import src.Controler.*
            import src.Context.*
            app.resetInputPanels();
            app.resetOutputPanels();
            app.forecast.Visible = false;
            Controler.getInstance().execute(Context(Events.GUI_MAIN, NaN));
        end

        % Image clicked function: searchImage
        function searchImageClicked(app, event)
            import src.Controler.*
            import src.Context.*
            import src.Transfer.*
            day = app.DayoftheweekDropDown.Value;
            prediction = app.DaystopredictSpinner.Value;
            tThingSpeak = TThingSpeak(1005862, 'XWEKXC29HGN748Y4', [1,2], {prediction, day}, NaN, NaN, NaN);
            uialert(app.forecast, "Making the prediction", 'Success', 'Icon', 'success');
            Controler.getInstance().execute(Context(Events.EXECUTE_FORECAST_WRITE, tThingSpeak));
        end

        % Close request function: forecast
        function forecastCloseRequest(app, event)
            close all force;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.forecast = uifigure('Visible', 'off');
            app.forecast.Name = 'Smart Cities';
            app.forecast.CloseRequestFcn = createCallbackFcn(app, @forecastCloseRequest, true);
            app.Panel = uipanel(app.forecast);
            app.GridLayout = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ForecastLabel = uilabel(app.GridLayout3);
            app.ForecastLabel.Text = ' Forecast';
            app.Image5 = uiimage(app.GridLayout3);
            app.Image5.ImageSource = 'forecast-icon-17.jpg';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout12 = uigridlayout(app.GridLayout4);
            app.UITable = uitable(app.GridLayout12);
            app.UITable.ColumnName = {'Date'; 'Vehicles'};
            app.UITable.RowName = {''; ''};
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.DaystopredictLabel = uilabel(app.GridLayout13);
            app.DaystopredictLabel.Text = 'Days to predict:';
            app.DaystopredictSpinner = uispinner(app.GridLayout13);
            app.DaystopredictSpinner.UpperLimitInclusive = 'off';
            app.DaystopredictSpinner.Limits = [1 Inf];
            app.DaystopredictSpinner.RoundFractionalValues = 'on';
            app.DaystopredictSpinner.Value = 1;
            app.DayoftheweekLabel = uilabel(app.GridLayout13);
            app.DayoftheweekLabel.Text = 'Day of the week:';
            app.DayoftheweekDropDown = uidropdown(app.GridLayout13);
            app.DayoftheweekDropDown.Items = {'Monday', 'Tuesday', 'Wednesday', 'Thursday', 'Friday', 'Saturday', 'Sunday'};
            app.DayoftheweekDropDown.Value = 'Monday';
            app.ThepredictionbeginsonMarch2020Label = uilabel(app.GridLayout4);
            app.ThepredictionbeginsonMarch2020Label.Text = 'The prediction begins on March, 2020';
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Prediction')
            xlabel(app.UIAxes, 'Dates')
            ylabel(app.UIAxes, 'Vehicles')
            app.GridLayout11 = uigridlayout(app.GridLayout);
            app.backImage = uiimage(app.GridLayout11);
            app.backImage.ImageClickedFcn = createCallbackFcn(app, @backImageClicked, true);
            app.backImage.ImageSource = 'reply.png';
            app.searchImage = uiimage(app.GridLayout11);
            app.searchImage.ImageClickedFcn = createCallbackFcn(app, @searchImageClicked, true);
            app.searchImage.ImageSource = 'Search_ballonicon2.svg.png';
            % Show the figure after all components are created
            app.forecast.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Forecast

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.forecast)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.forecast)
        end
    end
end