classdef Learning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        learning                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        GridLayout4              matlab.ui.container.GridLayout
        ImageResizerImage        matlab.ui.control.Image
        ImageResizerLabel        matlab.ui.control.Label
        GridLayout5              matlab.ui.container.GridLayout
        UpdateTrainingDataLabel  matlab.ui.control.Label
        UpdateTrainingDataImage  matlab.ui.control.Image
        GridLayout6              matlab.ui.container.GridLayout
        RetrainingLabel          matlab.ui.control.Label
        Image10                  matlab.ui.control.Image
        GridLayout7              matlab.ui.container.GridLayout
        BackImage                matlab.ui.control.Image
        GridLayout3              matlab.ui.container.GridLayout
        LearningLabel_2          matlab.ui.control.Label
        Image5                   matlab.ui.control.Image
    end

    
    methods (Access = public)
        
        function update(app, context)
            import src.Context.*
            event = getEvent(context);
            switch event
            case Events.GUI_LEARNING
                app.learning.Visible = true;
                drawnow;
                app.learning.WindowState = 'maximized';
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.learning.Visible = false;
        end

        % Image clicked function: BackImage
        function BackImageClicked(app, event)
            import src.Controler.*
            import src.Context.*
            app.learning.Visible = false;
            Controler.getInstance().execute(Context(Events.GUI_MAIN, NaN));
        end

        % Image clicked function: Image10
        function RetrainingImageClicked(app, event)
            import src.Controler.*
            import src.Context.*
            app.learning.Visible = false;
            Controler.getInstance().execute(Context(Events.GUI_RETRAINING, NaN));
        end

        % Image clicked function: UpdateTrainingDataImage
        function UpdateTrainingDataImageClicked(app, event)
            if isunix
                % Code to run on Linux platform
                system("nautilus --new-window " + fullfile(pwd,'src',"ImageCategories"));
            elseif ispc
                % Code to run on Windows platform
                winopen(fullfile(pwd, 'src', "ImageCategories"));
            else
                disp('Platform not supported')
            end
        end

        % Image clicked function: ImageResizerImage
        function ImageResizerImageClicked(app, event)
            resize_image_folder = fullfile(pwd);
            resize_image_destiny_folder = fullfile(pwd, 'src',"ResizedImages");  
            [filename,user_canceled] = imgetfile('InitialPath',resize_image_folder,'MultiSelect',true);
            if ~user_canceled
                [nRow, nCol] = size(filename);
                for row=1:nRow
                    for col=1:nCol
                        I = imread(filename{row, col});
                        I = imresize(I, [227 227]);
                        [filepath,name,ext] = fileparts(filename{row, col});
                        imwrite(I,fullfile(resize_image_destiny_folder, strcat(name, ext)), strrep(ext, '.', ''));
                    end
                end
                if isunix
                    % Code to run on Linux platform
                    system("nautilus --new-window " +  resize_image_destiny_folder);
                elseif ispc
                    % Code to run on Windows platform
                    winopen(resize_image_destiny_folder);
                else
                    disp('Platform not supported')
                end                
            end
        end

        % Close request function: learning
        function learningCloseRequest(app, event)
            close all force;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.learning = uifigure('Visible', 'off');
            app.learning.Name = 'Smart Cities';
            app.learning.CloseRequestFcn = createCallbackFcn(app, @learningCloseRequest, true);
            app.Panel = uipanel(app.learning);
            app.GridLayout = uigridlayout(app.Panel);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.ImageResizerImage = uiimage(app.GridLayout4);
            app.ImageResizerImage.ImageClickedFcn = createCallbackFcn(app, @ImageResizerImageClicked, true);
            app.ImageResizerImage.ImageSource = 'logo-fb-en.png';
            app.ImageResizerLabel = uilabel(app.GridLayout4);
            app.ImageResizerLabel.Text = 'Image Resizer';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.UpdateTrainingDataLabel = uilabel(app.GridLayout5);
            app.UpdateTrainingDataLabel.Text = {'Update Training'; 'Data'};
            app.UpdateTrainingDataImage = uiimage(app.GridLayout5);
            app.UpdateTrainingDataImage.ImageClickedFcn = createCallbackFcn(app, @UpdateTrainingDataImageClicked, true);
            app.UpdateTrainingDataImage.ImageSource = 'archivador_24908-40157.jpg';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.RetrainingLabel = uilabel(app.GridLayout6);
            app.RetrainingLabel.Text = 'Retraining';
            app.Image10 = uiimage(app.GridLayout6);
            app.Image10.ImageClickedFcn = createCallbackFcn(app, @RetrainingImageClicked, true);
            app.Image10.ImageSource = '171-1712673_tuercas-png-logo-de-tuercas-png.png';
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.BackImage = uiimage(app.GridLayout7);
            app.BackImage.ImageClickedFcn = createCallbackFcn(app, @BackImageClicked, true);
            app.BackImage.ImageSource = 'reply.png';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.LearningLabel_2 = uilabel(app.GridLayout3);
            app.LearningLabel_2.Text = 'Learning';
            app.Image5 = uiimage(app.GridLayout3);
            app.Image5.ImageSource = 'machineLearning.png';
            % Show the figure after all components are created
            app.learning.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Learning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.learning)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.learning)
        end
    end
end