classdef Queries < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        queries                 matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        GridLayout3             matlab.ui.container.GridLayout
        ThingSpeakQueriesLabel  matlab.ui.control.Label
        Image5                  matlab.ui.control.Image
        GridLayout4             matlab.ui.container.GridLayout
        GridLayout5             matlab.ui.container.GridLayout
        GridLayout6             matlab.ui.container.GridLayout
        EndDateLabel            matlab.ui.control.Label
        EndDatePicker           matlab.ui.control.DatePicker
        GridLayout9             matlab.ui.container.GridLayout
        InitDateLabel           matlab.ui.control.Label
        InitDatePicker          matlab.ui.control.DatePicker
        GridLayout10            matlab.ui.container.GridLayout
        FrontCarCheckBox        matlab.ui.control.CheckBox
        BackCarCheckBox         matlab.ui.control.CheckBox
        FrontTruckCheckBox      matlab.ui.control.CheckBox
        BackTruckCheckBox       matlab.ui.control.CheckBox
        FrontMotorbikeCheckBox  matlab.ui.control.CheckBox
        BackMotorbikeCheckBox   matlab.ui.control.CheckBox
        FrontBusCheckBox        matlab.ui.control.CheckBox
        BackBusCheckBox         matlab.ui.control.CheckBox
        UIAxes                  matlab.ui.control.UIAxes
        UITable                 matlab.ui.control.Table
        GridLayout11            matlab.ui.container.GridLayout
        backImage               matlab.ui.control.Image
        searchImage             matlab.ui.control.Image
    end

    
    properties (Access = private)
        vehicleTypeSelectors
    end
    
    methods (Access = public)
        function update(app, context)
            import src.Context.*
            event = getEvent(context);
            switch event
                case Events.GUI_QUERIES
                    app.queries.Visible = true;
                    drawnow;
                    app.queries.WindowState = 'maximized';
                case Events.EXECUTE_QUERIES_OK
                    app.resetOutputPanels();
                    app.queries.Visible = true;
                    drawnow;
                    app.queries.WindowState = 'maximized';
                    data = context.getData();
                    app.fillTable(data);
                case Events.EXECUTE_QUERIES_KO
                    app.queries.Visible = true;
                    drawnow;
                    app.queries.WindowState = 'maximized';
                    data = context.getData();
                    uialert(app.queries, data, 'Error', 'Icon','error');
            end
        end
    end
    
    methods (Access = private)
        
        function resetOutputPanels(app)
            % Create UITable
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {'Date'; 'Front Car'; 'Back Car'; 'Front Truck'; 'Back Truck'; 'Front Motorbike'; 'Back Motorbike'; 'Front Bus'; 'Back Bus'};
            app.UITable.RowName = {};
            app.UITable.Layout.Row = 1;
            app.UITable.Layout.Column = 2;
            
            % Create UIAxes
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.PlotBoxAspectRatio = [3.14864864864865 1 1];
            app.UIAxes.FontSize = 14;
            app.UIAxes.Box = 'on';
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.ZGrid = 'on';
            app.UIAxes.LabelFontSizeMultiplier = 1;
            app.UIAxes.TitleFontSizeMultiplier = 1;
            app.UIAxes.Layout.Row = 2;
            app.UIAxes.Layout.Column = 2;
        end
        
        function resetInputPanels(app)
            % Create FrontCarCheckBox
            app.FrontCarCheckBox = uicheckbox(app.GridLayout10);
            app.FrontCarCheckBox.Text = 'Front Car';
            app.FrontCarCheckBox.FontName = 'Pacifico';
            app.FrontCarCheckBox.FontSize = 30;
            app.FrontCarCheckBox.FontWeight = 'bold';
            app.FrontCarCheckBox.Layout.Row = 1;
            app.FrontCarCheckBox.Layout.Column = 1;

            % Create BackCarCheckBox
            app.BackCarCheckBox = uicheckbox(app.GridLayout10);
            app.BackCarCheckBox.Text = 'Back Car';
            app.BackCarCheckBox.FontName = 'Pacifico';
            app.BackCarCheckBox.FontSize = 30;
            app.BackCarCheckBox.FontWeight = 'bold';
            app.BackCarCheckBox.Layout.Row = 1;
            app.BackCarCheckBox.Layout.Column = 2;

            % Create FrontTruckCheckBox
            app.FrontTruckCheckBox = uicheckbox(app.GridLayout10);
            app.FrontTruckCheckBox.Text = 'Front Truck';
            app.FrontTruckCheckBox.FontName = 'Pacifico';
            app.FrontTruckCheckBox.FontSize = 30;
            app.FrontTruckCheckBox.FontWeight = 'bold';
            app.FrontTruckCheckBox.Layout.Row = 2;
            app.FrontTruckCheckBox.Layout.Column = 1;

            % Create BackTruckCheckBox
            app.BackTruckCheckBox = uicheckbox(app.GridLayout10);
            app.BackTruckCheckBox.Text = 'Back Truck';
            app.BackTruckCheckBox.FontName = 'Pacifico';
            app.BackTruckCheckBox.FontSize = 30;
            app.BackTruckCheckBox.FontWeight = 'bold';
            app.BackTruckCheckBox.Layout.Row = 2;
            app.BackTruckCheckBox.Layout.Column = 2;

            % Create FrontMotorbikeCheckBox
            app.FrontMotorbikeCheckBox = uicheckbox(app.GridLayout10);
            app.FrontMotorbikeCheckBox.Text = 'Front Motorbike';
            app.FrontMotorbikeCheckBox.FontName = 'Pacifico';
            app.FrontMotorbikeCheckBox.FontSize = 30;
            app.FrontMotorbikeCheckBox.FontWeight = 'bold';
            app.FrontMotorbikeCheckBox.Layout.Row = 3;
            app.FrontMotorbikeCheckBox.Layout.Column = 1;

            % Create BackMotorbikeCheckBox
            app.BackMotorbikeCheckBox = uicheckbox(app.GridLayout10);
            app.BackMotorbikeCheckBox.Text = 'Back Motorbike';
            app.BackMotorbikeCheckBox.FontName = 'Pacifico';
            app.BackMotorbikeCheckBox.FontSize = 30;
            app.BackMotorbikeCheckBox.FontWeight = 'bold';
            app.BackMotorbikeCheckBox.Layout.Row = 3;
            app.BackMotorbikeCheckBox.Layout.Column = 2;

            % Create FrontBusCheckBox
            app.FrontBusCheckBox = uicheckbox(app.GridLayout10);
            app.FrontBusCheckBox.Text = 'Front Bus';
            app.FrontBusCheckBox.FontName = 'Pacifico';
            app.FrontBusCheckBox.FontSize = 30;
            app.FrontBusCheckBox.FontWeight = 'bold';
            app.FrontBusCheckBox.Layout.Row = 4;
            app.FrontBusCheckBox.Layout.Column = 1;

            % Create BackBusCheckBox
            app.BackBusCheckBox = uicheckbox(app.GridLayout10);
            app.BackBusCheckBox.Text = 'Back Bus';
            app.BackBusCheckBox.FontName = 'Pacifico';
            app.BackBusCheckBox.FontSize = 30;
            app.BackBusCheckBox.FontWeight = 'bold';
            app.BackBusCheckBox.Layout.Row = 4;
            app.BackBusCheckBox.Layout.Column = 2;
            
            % Create EndDatePicker
            app.EndDatePicker = uidatepicker(app.GridLayout6);
            app.EndDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.EndDatePicker.FontName = 'Pacifico';
            app.EndDatePicker.FontSize = 35;
            app.EndDatePicker.FontWeight = 'bold';
            app.EndDatePicker.Layout.Row = 1;
            app.EndDatePicker.Layout.Column = 2;
            
            % Create InitDatePicker
            app.InitDatePicker = uidatepicker(app.GridLayout9);
            app.InitDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.InitDatePicker.FontName = 'Pacifico';
            app.InitDatePicker.FontSize = 35;
            app.InitDatePicker.FontWeight = 'bold';
            app.InitDatePicker.Layout.Row = 1;
            app.InitDatePicker.Layout.Column = 2;
        end
        
        function fillTable(app, tThingSpeakResult)
            import src.Transfer.*
            fields = tThingSpeakResult.getLabels();
            data = tThingSpeakResult.getData();
            timestamps = tThingSpeakResult.getTimestamps();
            channelInfo = tThingSpeakResult.getChannelInfo();
            %Funcion que rellena la tabla
            app.UITable.ColumnName = {"Date"};
            for i=1:length(fields)
                app.UITable.ColumnName{end+1} = channelInfo.FieldDescriptions{fields(i)};
            end
            r = horzcat(string(timestamps), string(data));
            total = ["TOTAL", string(sum(data))];
            r = vertcat(r,total);
            set(app.UITable,'Data',cellstr(r));
            drawnow;
            if (sum(data) > 0)
                app.createGraphic(timestamps, data);
            end
        end
        
        function createGraphic(app, timestamps, data)
            plot(timestamps, data,'-o','Parent', app.UIAxes);
            legend(app.UIAxes, app.UITable.ColumnName(2:1:end));
            minimo = min(min(data));
            if minimo > 0
                minimo = minimo-1;
            end
            ylim(app.UIAxes,[minimo, max(max(data))+1]);
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.queries.Visible = false;
            app.vehicleTypeSelectors = [app.FrontCarCheckBox, app.BackCarCheckBox,...
                app.FrontTruckCheckBox, app.BackTruckCheckBox, ...
                app.FrontMotorbikeCheckBox, app.BackMotorbikeCheckBox,...
                app.FrontBusCheckBox, app.BackBusCheckBox];
        end

        % Image clicked function: backImage
        function backImageClicked(app, event)
            import src.Controler.*
            import src.Context.*
            app.resetInputPanels();
            app.resetOutputPanels();
            app.queries.Visible = false;
            Controler.getInstance().execute(Context(Events.GUI_THING_SPEAK, NaN));
        end

        % Image clicked function: searchImage
        function searchImageClicked(app, event)
            import src.Controler.*
            import src.Context.*
            import src.Transfer.*
            fields = int16.empty();
            for i=1:8
                if  get(app.vehicleTypeSelectors(i),'value')
                    fields(end+1) = i;
                end
            end
            initDate = get(app.InitDatePicker,'value');
            endDate = get(app.EndDatePicker,'value');
            % Resistriciones
            if isnat(initDate)
                %Mensaje de error
                uialert(app.queries,"You need to introduce an init date", 'Error', 'Icon','error');
            elseif isnat(endDate)
                uialert(app.queries,"You need to introduce an end date", 'Error', 'Icon','error');
                %Mensaje de error
            elseif isempty(fields)
                %Mensaje de error
                uialert(app.queries,"You need to select at least one vehicle", 'Error', 'Icon','error');
                
            else
                % Para convertir las fechas en numeros para poder
                % compararlas
                formatIn = 'yyyy-mm-dd';
                dates = datenum([string(initDate),string(endDate)],formatIn);
                % La fecha fin(dates(2)), es menor que la fecha de inicio(dates(1))
                if dates(2) <= dates(1)
                    %Mensaje de error
                    uialert(app.queries,"The init date is after or equal than the end date", 'Error', 'Icon','error');
                else %Todo ha salido bien
                    tThingSpeak = TThingSpeak(986255, 'WB89SEHHHJYDEFFL', fields, NaN, initDate, endDate, NaN);
                    Controler.getInstance().execute(Context(Events.EXECUTE_QUERIES, tThingSpeak));
                end
            end
        end

        % Close request function: queries
        function queriesCloseRequest(app, event)
            close all force;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.queries = uifigure('Visible', 'off');
            app.queries.Name = 'Smart Cities';
            app.queries.CloseRequestFcn = createCallbackFcn(app, @queriesCloseRequest, true);
            app.Panel = uipanel(app.queries);
            app.GridLayout = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ThingSpeakQueriesLabel = uilabel(app.GridLayout3);
            app.ThingSpeakQueriesLabel.Text = 'ThingSpeak Queries';
            app.Image5 = uiimage(app.GridLayout3);
            app.Image5.ImageSource = 'google-big-query-logo.png';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.EndDateLabel = uilabel(app.GridLayout6);
            app.EndDateLabel.Text = 'End Date:';
            app.EndDatePicker = uidatepicker(app.GridLayout6);
            app.EndDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.InitDateLabel = uilabel(app.GridLayout9);
            app.InitDateLabel.Text = 'Init Date:';
            app.InitDatePicker = uidatepicker(app.GridLayout9);
            app.InitDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.GridLayout10 = uigridlayout(app.GridLayout4);
            app.FrontCarCheckBox = uicheckbox(app.GridLayout10);
            app.FrontCarCheckBox.Text = 'Front Car';
            app.BackCarCheckBox = uicheckbox(app.GridLayout10);
            app.BackCarCheckBox.Text = 'Back Car';
            app.FrontTruckCheckBox = uicheckbox(app.GridLayout10);
            app.FrontTruckCheckBox.Text = 'Front Truck';
            app.BackTruckCheckBox = uicheckbox(app.GridLayout10);
            app.BackTruckCheckBox.Text = 'Back Truck';
            app.FrontMotorbikeCheckBox = uicheckbox(app.GridLayout10);
            app.FrontMotorbikeCheckBox.Text = 'Front Motorbike';
            app.BackMotorbikeCheckBox = uicheckbox(app.GridLayout10);
            app.BackMotorbikeCheckBox.Text = 'Back Motorbike';
            app.FrontBusCheckBox = uicheckbox(app.GridLayout10);
            app.FrontBusCheckBox.Text = 'Front Bus';
            app.BackBusCheckBox = uicheckbox(app.GridLayout10);
            app.BackBusCheckBox.Text = 'Back Bus';
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {'Date'; 'Front Car'; 'Back Car'; 'Front Truck'; 'Back Truck'; 'Front Motorbike'; 'Back Motorbike'; 'Front Bus'; 'Back Bus'};
            app.UITable.RowName = {};
            app.GridLayout11 = uigridlayout(app.GridLayout);
            app.backImage = uiimage(app.GridLayout11);
            app.backImage.ImageClickedFcn = createCallbackFcn(app, @backImageClicked, true);
            app.backImage.ImageSource = 'reply.png';
            app.searchImage = uiimage(app.GridLayout11);
            app.searchImage.ImageClickedFcn = createCallbackFcn(app, @searchImageClicked, true);
            app.searchImage.ImageSource = 'Search_ballonicon2.svg.png';
            % Show the figure after all components are created
            app.queries.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Queries

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.queries)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.queries)
        end
    end
end