classdef SmartCities < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        smartCities        matlab.ui.Figure
        Panel              matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        GridLayout2        matlab.ui.container.GridLayout
        GridLayout4        matlab.ui.container.GridLayout
        LearningImages     matlab.ui.control.Image
        LearningLabel      matlab.ui.control.Label
        GridLayout5        matlab.ui.container.GridLayout
        CarImage           matlab.ui.control.Image
        CarDetectionLabel  matlab.ui.control.Label
        GridLayout6        matlab.ui.container.GridLayout
        ThingSpeakLabel    matlab.ui.control.Label
        ThingSpeakImage    matlab.ui.control.Image
        GridLayout7        matlab.ui.container.GridLayout
        ForecastLabel      matlab.ui.control.Label
        ForecastImage      matlab.ui.control.Image
        GridLayout3        matlab.ui.container.GridLayout
        SMARTLabel         matlab.ui.control.Label
        CitiesLabel        matlab.ui.control.Label
    end

    
    methods (Access = public)      
        function update(app, context)
            import src.Context.*
            event = getEvent(context);
            switch event
            case Events.GUI_MAIN
                app.smartCities.Visible = true;
                drawnow;
                app.smartCities.WindowState = 'maximized';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.smartCities.Visible = false;
            addpath(genpath(pwd));
            savepath;
        end

        % Image clicked function: LearningImages
        function LearningImagesClicked(app, event)
            import src.Controler.*
            import src.Context.*
            app.smartCities.Visible = false;
            Controler.getInstance().execute(Context(Events.GUI_LEARNING, NaN));
        end

        % Image clicked function: ThingSpeakImage
        function ThingSpeakImageClicked(app, event)
            import src.Controler.*
            import src.Context.*
            app.smartCities.Visible = false;
            Controler.getInstance().execute(Context(Events.GUI_THING_SPEAK, NaN));
        end

        % Image clicked function: CarImage
        function CarImageClicked(app, event)
            import src.Controler.*
            import src.Context.*
            app.smartCities.Visible = false;
            Controler.getInstance().execute(Context(Events.GUI_CAR_DETECTION, NaN));
        end

        % Image clicked function: ForecastImage
        function ForecastImageClicked(app, event)
            import src.Controler.*
            import src.Context.*
            app.smartCities.Visible = false;
            Controler.getInstance().execute(Context(Events.GUI_FORECAST, NaN));
        end

        % Close request function: smartCities
        function smartCitiesCloseRequest(app, event)
            close all force;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.smartCities = uifigure('Visible', 'off');
            app.smartCities.Name = 'Smart Cities';
            app.smartCities.CloseRequestFcn = createCallbackFcn(app, @smartCitiesCloseRequest, true);
            app.Panel = uipanel(app.smartCities);
            app.GridLayout = uigridlayout(app.Panel);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.LearningImages = uiimage(app.GridLayout4);
            app.LearningImages.ImageClickedFcn = createCallbackFcn(app, @LearningImagesClicked, true);
            app.LearningImages.ImageSource = 'machineLearning.png';
            app.LearningLabel = uilabel(app.GridLayout4);
            app.LearningLabel.Text = 'Learning';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.CarImage = uiimage(app.GridLayout5);
            app.CarImage.ImageClickedFcn = createCallbackFcn(app, @CarImageClicked, true);
            app.CarImage.ImageSource = 'car.png';
            app.CarDetectionLabel = uilabel(app.GridLayout5);
            app.CarDetectionLabel.Text = 'Car Detection';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.ThingSpeakLabel = uilabel(app.GridLayout6);
            app.ThingSpeakLabel.Text = 'ThingSpeak';
            app.ThingSpeakImage = uiimage(app.GridLayout6);
            app.ThingSpeakImage.ImageClickedFcn = createCallbackFcn(app, @ThingSpeakImageClicked, true);
            app.ThingSpeakImage.ImageSource = 'thingspeak_logo.png';
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.ForecastLabel = uilabel(app.GridLayout7);
            app.ForecastLabel.Text = 'Forecast';
            app.ForecastImage = uiimage(app.GridLayout7);
            app.ForecastImage.ImageClickedFcn = createCallbackFcn(app, @ForecastImageClicked, true);
            app.ForecastImage.ImageSource = 'forecast-icon-17.jpg';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SMARTLabel = uilabel(app.GridLayout3);
            app.SMARTLabel.Text = 'SMART';
            app.CitiesLabel = uilabel(app.GridLayout3);
            app.CitiesLabel.Text = 'Cities';
            % Show the figure after all components are created
            app.smartCities.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmartCities

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.smartCities)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.smartCities)
        end
    end
end