classdef ThingSpeak < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        thingSpeak          matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        GridLayout          matlab.ui.container.GridLayout
        GridLayout2         matlab.ui.container.GridLayout
        GridLayout4         matlab.ui.container.GridLayout
        GridLayout5         matlab.ui.container.GridLayout
        QueriesLabel        matlab.ui.control.Label
        QueriesImage        matlab.ui.control.Image
        GridLayout6         matlab.ui.container.GridLayout
        ThingSpeakWebImage  matlab.ui.control.Image
        ThingSpeakWebLabel  matlab.ui.control.Label
        GridLayout7         matlab.ui.container.GridLayout
        backImage           matlab.ui.control.Image
        GridLayout3         matlab.ui.container.GridLayout
        ThingSpeakLabel     matlab.ui.control.Label
        Image5              matlab.ui.control.Image
    end

    methods (Access = public)
        function update(app, context)
            import src.Context.*
            event = getEvent(context);
            switch event
            case Events.GUI_THING_SPEAK
                app.thingSpeak.Visible = true;
                drawnow;
                app.thingSpeak.WindowState = 'maximized';
            end
        end
     end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.thingSpeak.Visible = false;
        end

        % Image clicked function: backImage
        function backImageClicked(app, event)
            import src.Controler.*
            import src.Context.*
            app.thingSpeak.Visible = false;
            execute(Controler.getInstance(),Context(Events.GUI_MAIN, NaN));
        end

        % Image clicked function: ThingSpeakWebImage
        function ThingSpeakWebImageClicked(app, event)
            web https://thingspeak.com/channels/986255 -browser
        end

        % Image clicked function: QueriesImage
        function QueriesImageClicked(app, event)
            import src.Controler.*
            import src.Context.*
            app.thingSpeak.Visible = false;
            execute(Controler.getInstance(),Context(Events.GUI_QUERIES, NaN));
        end

        % Close request function: thingSpeak
        function thingSpeakCloseRequest(app, event)
            close all force;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.thingSpeak = uifigure('Visible', 'off');
            app.thingSpeak.Name = 'Smart Cities';
            app.thingSpeak.CloseRequestFcn = createCallbackFcn(app, @thingSpeakCloseRequest, true);
            app.Panel = uipanel(app.thingSpeak);
            app.GridLayout = uigridlayout(app.Panel);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.QueriesLabel = uilabel(app.GridLayout5);
            app.QueriesLabel.Text = 'Queries';
            app.QueriesImage = uiimage(app.GridLayout5);
            app.QueriesImage.ImageClickedFcn = createCallbackFcn(app, @QueriesImageClicked, true);
            app.QueriesImage.ImageSource = 'google-big-query-logo.png';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.ThingSpeakWebImage = uiimage(app.GridLayout6);
            app.ThingSpeakWebImage.ImageClickedFcn = createCallbackFcn(app, @ThingSpeakWebImageClicked, true);
            app.ThingSpeakWebImage.ImageSource = 'ThingSpeak-Web.png';
            app.ThingSpeakWebLabel = uilabel(app.GridLayout6);
            app.ThingSpeakWebLabel.Text = 'ThingSpeak Web';
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.backImage = uiimage(app.GridLayout7);
            app.backImage.ImageClickedFcn = createCallbackFcn(app, @backImageClicked, true);
            app.backImage.ImageSource = 'reply.png';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ThingSpeakLabel = uilabel(app.GridLayout3);
            app.ThingSpeakLabel.Text = 'ThingSpeak';
            app.Image5 = uiimage(app.GridLayout3);
            app.Image5.ImageSource = 'thingspeak_logo2.png';
            % Show the figure after all components are created
            app.thingSpeak.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThingSpeak

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.thingSpeak)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.thingSpeak)
        end
    end
end